classdef PID_Temp_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentTemperatureLabel         matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        PIDControlPanel                 matlab.ui.container.Panel
        IntegralConstantEditField       matlab.ui.control.NumericEditField
        IntegralConstantEditFieldLabel  matlab.ui.control.Label
        DerivativeConstantEditField     matlab.ui.control.NumericEditField
        DerivativeConstantEditFieldLabel  matlab.ui.control.Label
        ProportionalConstantEditField   matlab.ui.control.NumericEditField
        ProportionalConstantEditFieldLabel  matlab.ui.control.Label
        QuitButton                      matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        TemperatureSettingEditField     matlab.ui.control.NumericEditField
        TemperatureSettingEditFieldLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            global comport
            global port
            if ~isempty(port)
                comport = serial(port,'BaudRate',115200);
                fclose(comport);
                clear comport;
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
                close
                delete (app)
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global comport
            global port
            if ~isempty(port)
                NUM_POINTS = 400;
                beta = 3944; 
                R0 = 10000;
                T0 = 298.15;

                comport = serial(port,'BaudRate',115200);
                fopen(comport);
                app.Lamp.Color = 'green'; % Lamp indicates that successful connection has been established

                % Send a character to synchronize the transmission
                fprintf(comport,'%s', '0');
                
                currTemp = 21;
                temps = [];
                setTemps = [];
                time_step = NUM_POINTS*30/115200;
                time_diffs = ones(500);
                for i = 1:500
                    time_diffs(i) = (i-1)*time_step;
                end
                times = zeros(500) + time_diffs;
                perrlist = zeros(1,5);
                iError = 0;
                sums = 0;
                pError = 0;
                prevError = 0;

                global setTemp
                global changedTemp
                global kP
                global kD
                global kI
                
                
                kP = app.ProportionalConstantEditField.Value;
                kI = app.IntegralConstantEditField.Value;
                kD = app.DerivativeConstantEditField.Value;
    
                while(1)
                     data = fread(comport, NUM_POINTS, 'uint8');
                     % Convert values to temperature
                     voltage = mean(data)/255*3.3;
                     res = 9900*(3.3./voltage-1);
                     currTemp = 1/(1/beta*log(res./R0)+1/T0)-273.15;  % Thermistor equation
                     %currTemp = -22.88*log(res) + 236.44   %curve fit

                     if length(temps) == 500
                         temps = [];
                         setTemps = [];
                         times = time_diffs + times(500);
                     end
                     
                     app.CurrentTemperatureLabel.Text = ['Current Temperature (deg C): ' num2str(currTemp, 4)];
                     temps = [temps, currTemp];
                     setTemp = app.TemperatureSettingEditField.Value;
                     setTemps = [setTemps, setTemp];

                     if (setTemp >= 5 && setTemp < 5.5)
                         setTemp = setTemp - 1.8;
                     elseif (setTemp >= 5.5 && setTemp < 10)
                         setTemp = setTemp - 1.5;
                     elseif (setTemp >= 10 && setTemp < 13)
                         setTemp = setTemp - 1.25;
                     elseif (setTemp >= 13 && setTemp < 15)
                         setTemp = setTemp - 1.0;
                     elseif (setTemp >= 15 && setTemp <= 20)
                          setTemp = setTemp - 0.8;
                     elseif (setTemp > 25 && setTemp < 30)
                         setTemp = setTemp + 0.25;
                     elseif (setTemp >= 30 && setTemp < 35)
                           setTemp = setTemp + 0.5;
                     elseif (setTemp >= 35 && setTemp < 40)
                            setTemp = setTemp + 0.75;
                     elseif (setTemp >= 40 && setTemp < 43)
                            setTemp = setTemp + 0.9;
                     elseif (setTemp >= 43 && setTemp <= 45)
                            setTemp = setTemp + 1.2;
                     end

                     prevError = pError;
                     pError = setTemp - currTemp;
                    
                     perrlist = circshift(perrlist, [0,1]);
                     perrlist(1) = pError;
                     iError = sum(perrlist);
                     % iMax = 0.1;
                     % iMin = -0.1;
                     % if iError > iMax
                     %     iError = iMax;
                     % elseif iError < iMin
                     %     iError = iMin;
                     % end

                     dError = pError - prevError;
                     pwm = kP*pError +kD*dError+ kI*iError;
                     % For debugging only
                     %[pError, iError, dError, pwm]
                     
                     %Output Saturation
                     %pwm = pwm*128;

                     if pwm > 128
                         pwm = 128;
                     elseif pwm < -127
                         pwm = -127;
                     end
                     pwm = round(pwm + 127);
                     fprintf(comport, '%s', pwm);

                     % %fprintf(comport,'%c', '0');%pwm);
                     % if currTemp(1) > setTemp
                     %    isHot = '1';
                     % else
                     %    isHot = '0';
                     % end
                     % isHot;
                     %fprintf(comport,'%s', isHot);
                     
                     % data = fread(comport, NUM_POINTS, 'uint8');
                     % % Convert values to temperature
                     % voltage = mean(data)/255*3.3;
                     % res = 9900*(3.3./voltage-1);
                     % currTemp = 1/(1/beta*log(res./R0)+1/T0)-273.15;  % Thermistor equation
                     % %currTemp = -22.88*log(res) + 236.44   %curve fit

                     % if length(temps) == 500
                     %     temps = [];
                     %     setTemps = [];
                     %     times = time_diffs + times(500);
                     % end

                     % app.CurrentTemperatureLabel.Text = ['Current Temperature (deg C): ' num2str(currTemp, 4)];
                     % temps = [temps, currTemp];
                     % 

                     %currTemp

                     % Plotting
                     fin = length(temps);
                     plot(app.UIAxes, times(1:fin), temps, 'Color','blue');
                     hold(app.UIAxes,"on");
                     plot(app.UIAxes, times(1:fin), setTemps, 'Color','red');
                     hold(app.UIAxes, 'off');
                     %xlim(app.UIAxes, [0, 500]);
                     ylim(app.UIAxes, [0,50]);
                     drawnow;
                end
            end
        end

        % Value changed function: TemperatureSettingEditField
        function TemperatureSettingEditFieldValueChanged(app, event)
            global setTemp 
            global changedTemp
            setTemp = app.TemperatureSettingEditField.Value;
            changedTemp = true;
        end

        % Value changed function: ProportionalConstantEditField
        function ProportionalConstantEditFieldValueChanged(app, event)
            global kP
            kP = app.ProportionalConstantEditField.Value;
        end

        % Value changed function: DerivativeConstantEditField
        function DerivativeConstantEditFieldValueChanged(app, event)
            global kD
            kD = app.DerivativeConstantEditField.Value;
        end

        % Value changed function: IntegralConstantEditField
        function IntegralConstantEditFieldValueChanged(app, event)
            global kI
            kI = app.IntegralConstantEditField.Value;
        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            global port
            port = app.COMPortDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature of TEC')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TemperatureSettingEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureSettingEditFieldLabel.Text = 'Temperature Setting';
            app.TemperatureSettingEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureSettingEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureSettingEditFieldValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.PIDControlPanel = uipanel(app.UIFigure);
            app.PIDControlPanel.Title = 'PID Control';
            app.ProportionalConstantEditFieldLabel = uilabel(app.PIDControlPanel);
            app.ProportionalConstantEditFieldLabel.Text = 'Proportional Constant';
            app.ProportionalConstantEditField = uieditfield(app.PIDControlPanel, 'numeric');
            app.ProportionalConstantEditField.ValueChangedFcn = createCallbackFcn(app, @ProportionalConstantEditFieldValueChanged, true);
            app.ProportionalConstantEditField.Value = 1;
            app.DerivativeConstantEditFieldLabel = uilabel(app.PIDControlPanel);
            app.DerivativeConstantEditFieldLabel.Text = 'Derivative Constant';
            app.DerivativeConstantEditField = uieditfield(app.PIDControlPanel, 'numeric');
            app.DerivativeConstantEditField.ValueChangedFcn = createCallbackFcn(app, @DerivativeConstantEditFieldValueChanged, true);
            app.DerivativeConstantEditField.Value = 1;
            app.IntegralConstantEditFieldLabel = uilabel(app.PIDControlPanel);
            app.IntegralConstantEditFieldLabel.Text = 'Integral Constant';
            app.IntegralConstantEditField = uieditfield(app.PIDControlPanel, 'numeric');
            app.IntegralConstantEditField.ValueChangedFcn = createCallbackFcn(app, @IntegralConstantEditFieldValueChanged, true);
            app.IntegralConstantEditField.Value = 1;
            app.COMPortDropDownLabel = uilabel(app.UIFigure);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.UIFigure);
            app.COMPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.COMPortDropDown.Value = 'COM1';
            app.CurrentTemperatureLabel = uilabel(app.UIFigure);
            app.CurrentTemperatureLabel.Text = 'Current Temperature:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_Temp_Control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end